classdef App_Floyd_Warshall < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        saveGraph                     matlab.ui.control.Button
        drawMode                      matlab.ui.control.DropDown
        ChvthLabel                    matlab.ui.control.Label
        printUpdate                   matlab.ui.control.CheckBox
        RandomStartEnd                matlab.ui.control.Button
        AutoNaming                    matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        groupInfo                     matlab.ui.control.Label
        congcuVeDoThi                 matlab.ui.control.Label
        redHighlight                  matlab.ui.control.CheckBox
        NodeNames                     matlab.ui.control.EditField
        TnccnhEditFieldLabel          matlab.ui.control.Label
        HTML                          matlab.ui.control.HTML
        costMatrix                    matlab.ui.control.EditField
        MatrntrngsbanuEditFieldLabel  matlab.ui.control.Label
        ToNode                        matlab.ui.control.EditField
        nnhEditFieldLabel             matlab.ui.control.Label
        FromNode                      matlab.ui.control.EditField
        itnhEditFieldLabel            matlab.ui.control.Label
        Title                         matlab.ui.control.Label
        hcmut_Image                   matlab.ui.control.Image
        RunFloydWarshall_Button       matlab.ui.control.Button
        theGraph                      matlab.ui.control.UIAxes
    end

    % Helper functions
    methods (Access = public)

function name = convertIndexToName(~, index)
    name = "";
    while index > 0
        index = index - 1;
        name = string(char(mod(index, 26) + 'A')) + name;
        index = floor(index / 26);
    end
end

function nodeIndex = getNodeIndex(~, nodeName, nodeNames)
    nodeIndex = -1;
    for i = 1:length(nodeNames)
        if strcmp(nodeNames{i}, nodeName)
            nodeIndex = i;
            return;
        end
    end
    if nodeIndex == -1
        error('Đỉnh "%s" không tồn tại.', nodeName);
    end
end

function cycle = tao_chu_trinh_am(~, node, next)
    cycle = node;
    current = next(node, node);
    while current ~= node && ~ismember(current, cycle)
        cycle = [cycle, current];
        current = next(current, node);
    end
    cycle = [cycle, node];
end

function [shortestPath, totalCost, negativeCycle, finalCostMatrix, nextMatrix] = floyd_algorithm(app, costMatrix, startIndex, endIndex)
    numNodes = size(costMatrix, 1);
    cost = costMatrix;
    next = nan(numNodes);
    for i = 1:numNodes
        for j = 1:numNodes
            if cost(i, j) < inf
                next(i, j) = j;
            end
        end
    end
    iteration = 1;
    for k = 1:numNodes
        for i = 1:numNodes
            for j = 1:numNodes
                if cost(i,k) + cost(k,j) < cost(i,j)
                    oldCost = cost(i,j); 
                    cost(i,j) = cost(i,k) + cost(k,j);
                    next(i,j) = next(i,k);
                    if app.printUpdate.Value
                        fprintf('Lần so sánh %d | Đỉnh trung gian: %d | Cập nhật D(%d, %d) = %.2f (giá trị cũ: %.2f)\n', ...
                            iteration, k, i, j, cost(i,j), oldCost);
                    end
                end
                iteration = iteration + 1; 
            end
        end
    end

    finalCostMatrix = cost;
    nextMatrix = next;
    negativeCycle = [];
    for i = 1:numNodes
        if cost(i,i) < 0
            negativeCycle = tao_chu_trinh_am(app, i, next);
            shortestPath = [];
            totalCost = -inf;
            return;
        end
    end
    if isnan(next(startIndex, endIndex))
        shortestPath = [];
        totalCost = inf;
        return;
    end
    shortestPath = startIndex;
    while startIndex ~= endIndex
        startIndex = next(startIndex, endIndex);
        shortestPath = [shortestPath, startIndex];
    end
    totalCost = cost(shortestPath(1), shortestPath(end));
end

function in_ket_qua(~, shortestPath, totalCost, startIndex, endIndex, nodeNames, negativeCycle, finalCostMatrix, nextMatrix)
    fprintf('\n');
    disp('Ma trận chi phí cuối cùng:');
    disp(finalCostMatrix);
    disp('Ma trận truy vết đường đi:');
    disp(nextMatrix);
    if isempty(shortestPath)
        if isempty(negativeCycle)
            fprintf('Không có đường đi ngắn nhất từ %s đến %s.\n', nodeNames{startIndex}, nodeNames{endIndex});
        else
            fprintf('Chu trình âm: ');
            for i = 1:length(negativeCycle) - 1
                fprintf('%s -> ', nodeNames{negativeCycle(i)});
            end
            fprintf('%s\n', nodeNames{negativeCycle(end)});
        end
        return;
    end
    fprintf('Đường đi ngắn nhất từ %s đến %s: ', nodeNames{startIndex}, nodeNames{endIndex});
    for i = 1:length(shortestPath)-1
        fprintf('%s -> ', nodeNames{shortestPath(i)});
    end
    fprintf('%s\n', nodeNames{shortestPath(end)});
    fprintf('Tổng chi phí: %d\n', totalCost);
end

function ve_do_thi(app, ax, costMatrix, nodeNames, shortestPath, highlightOption, negativeCycle)
    s = [];
    t = [];
    weights = [];
    arrowSizes = [];

    n = size(costMatrix, 1);
    added = false(n);

    for i = 1:n
        for j = 1:n
            if i ~= j && isfinite(costMatrix(i, j))
                s(end+1) = i;
                t(end+1) = j;
                weights(end+1) = costMatrix(i, j);
                arrowSizes(end+1) = 15;
            end
        end
    end

    nodeNames = string(nodeNames);
    G = digraph(s, t, weights, nodeNames);
    cla(ax); 
    layoutMode = app.drawMode.Value;  

    p = plot(ax, G, ...
        'Layout', layoutMode, ...
        'EdgeLabel', G.Edges.Weight, ...
        'NodeLabel', G.Nodes.Name, ...
        'LineWidth', 2, ...
        'MarkerSize', 10, ...
        'NodeFontSize', 14, ...
        'NodeFontWeight', 'bold', ...
        'NodeLabelColor', 'b', ...
        'EdgeColor', 'k', ...
        'NodeColor', 'b');

    for k = 1:numedges(G)
        highlight(p, G.Edges.EndNodes(k, :), 'ArrowSize', arrowSizes(k));
    end

    title(ax, 'Đồ thị');

    if highlightOption
        if ~isempty(shortestPath)
            highlight(p, shortestPath, 'EdgeColor', 'r', 'LineWidth', 6, 'ArrowSize', 20);
            highlight(p, shortestPath, 'NodeColor', 'r', 'MarkerSize', 10);
        end
    
        if ~isempty(negativeCycle)
            highlight(p, negativeCycle, 'EdgeColor', 'g', 'LineWidth', 5, 'ArrowSize', 18);
            highlight(p, negativeCycle, 'NodeColor', 'g', 'MarkerSize', 10);
        end
    end
end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunFloydWarshall_Button
        function RunFloydWarshall_ButtonPushed(app, event)
    try
        costMatrix = str2num(app.costMatrix.Value);
        nodeNames = eval(app.NodeNames.Value); 
        fromName = strtrim(app.FromNode.Value);
        toName = strtrim(app.ToNode.Value);
        highlightOn = app.redHighlight.Value;
        clc;

        if length(nodeNames) ~= size(costMatrix, 1)
            uialert(app.UIFigure, 'Số lượng tên đỉnh không khớp với ma trận.', 'Lỗi');
            return;
        end

        startIndex = getNodeIndex(app,fromName, nodeNames);
        endIndex = getNodeIndex(app,toName, nodeNames);

        [shortestPath, totalCost, negativeCycle, finalCostMatrix, nextMatrix] = ...
            floyd_algorithm(app, costMatrix, startIndex, endIndex);

        in_ket_qua(app, shortestPath, totalCost, startIndex, endIndex, nodeNames, negativeCycle, finalCostMatrix, nextMatrix);

        ve_do_thi(app, app.theGraph, costMatrix, nodeNames, shortestPath, highlightOn, negativeCycle);

    catch ME
        uialert(app.UIFigure, ME.message, 'Lỗi');
    end
        end

        % Value changed function: redHighlight
        function redHighlightValueChanged(app, event)
            RunFloydWarshall_ButtonPushed(app, event)
        end

        % Button pushed function: AutoNaming
        function AutoNamingPushed(app, event)
            costMatrix = str2num(app.costMatrix.Value);
            n = size(costMatrix, 1);
            names = strings(1, n);
            for i = 1:n
                names(i) = convertIndexToName(app, i);
            end
            nameString = "{" + strjoin("""" + names + """", ", ") + "}";
            app.NodeNames.Value = nameString;
        end

        % Button pushed function: RandomStartEnd
        function RandomStartEndButtonPushed(app, event)
            nodeNames = eval(app.NodeNames.Value);  
            if iscell(nodeNames) && ~isempty(nodeNames)
                index = randi(length(nodeNames));  
                app.FromNode.Value = nodeNames{index};
                index = randi(length(nodeNames));  
                app.ToNode.Value = nodeNames{index};
            else
                uialert(app.UIFigure, 'Danh sách node không hợp lệ hoặc rỗng.', 'Lỗi');
            end
        end

        % Value changed function: drawMode
        function drawModeValueChanged(app, event)
            RunFloydWarshall_ButtonPushed(app, event)
        end

        % Value changed function: printUpdate
        function printUpdateValueChanged(app, event)
            RunFloydWarshall_ButtonPushed(app, event)
        end

        % Button pushed function: saveGraph
        function saveGraphButtonPushed(app, event)
            f = figure('Visible', 'off');
            f.Position = [100 100 1000 800];  
            axNew = copyobj(app.theGraph, f);
            set(axNew, 'Units', 'normalized');
            set(axNew, 'Position', [0.1 0.1 0.8 0.8]); 
            exportgraphics(f, 'DoThi.png', 'Resolution', 300);  
            close(f);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.theGraph = uiaxes(app.UIFigure);
            app.RunFloydWarshall_Button = uibutton(app.UIFigure, 'push');
            app.RunFloydWarshall_Button.ButtonPushedFcn = createCallbackFcn(app, @RunFloydWarshall_ButtonPushed, true);
            app.RunFloydWarshall_Button.Text = {'Chạy thuật toán'; 'Floyd - Warshall'};
            app.hcmut_Image = uiimage(app.UIFigure);
            app.hcmut_Image.ImageSource = 'logo.png';
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'Thuật toán Floyd-Warshall tìm đường đi ngắn nhất';
            app.itnhEditFieldLabel = uilabel(app.UIFigure);
            app.itnhEditFieldLabel.Text = 'Đi từ đỉnh';
            app.FromNode = uieditfield(app.UIFigure, 'text');
            app.nnhEditFieldLabel = uilabel(app.UIFigure);
            app.nnhEditFieldLabel.Text = 'Đến đỉnh';
            app.ToNode = uieditfield(app.UIFigure, 'text');
            app.MatrntrngsbanuEditFieldLabel = uilabel(app.UIFigure);
            app.MatrntrngsbanuEditFieldLabel.Text = 'Ma trận trọng số ban đầu';
            app.costMatrix = uieditfield(app.UIFigure, 'text');
            app.HTML = uihtml(app.UIFigure);
            app.HTML.HTMLSource = 'index.html';
            app.TnccnhEditFieldLabel = uilabel(app.UIFigure);
            app.TnccnhEditFieldLabel.Text = 'Tên các đỉnh';
            app.NodeNames = uieditfield(app.UIFigure, 'text');
            app.redHighlight = uicheckbox(app.UIFigure);
            app.redHighlight.ValueChangedFcn = createCallbackFcn(app, @redHighlightValueChanged, true);
            app.redHighlight.Text = 'Tô đỏ đường đi ngắn nhất / tô xanh chu trình âm';
            app.redHighlight.Value = true;
            app.congcuVeDoThi = uilabel(app.UIFigure);
            app.congcuVeDoThi.Text = 'Công cụ vẽ đồ thị';
            app.groupInfo = uilabel(app.UIFigure);
            app.groupInfo.Text = 'Bài tâp lớn L15, Nhóm 8 - Đại số tuyến tính, HK 242 (5/2025)';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'MATLAB-logo.png');
            app.AutoNaming = uibutton(app.UIFigure, 'push');
            app.AutoNaming.ButtonPushedFcn = createCallbackFcn(app, @AutoNamingPushed, true);
            app.AutoNaming.Text = 'Tự động đặt tên A, B, C,...';
            app.RandomStartEnd = uibutton(app.UIFigure, 'push');
            app.RandomStartEnd.ButtonPushedFcn = createCallbackFcn(app, @RandomStartEndButtonPushed, true);
            app.RandomStartEnd.Text = 'Chọn ngẫu nhiên';
            app.printUpdate = uicheckbox(app.UIFigure);
            app.printUpdate.ValueChangedFcn = createCallbackFcn(app, @printUpdateValueChanged, true);
            app.printUpdate.Text = 'In những lần cập nhật ma trận lên Command Window';
            app.printUpdate.Value = true;
            app.ChvthLabel = uilabel(app.UIFigure);
            app.ChvthLabel.Text = 'Chế độ vẽ đồ thị';
            app.drawMode = uidropdown(app.UIFigure);
            app.drawMode.Items = {'auto', 'circle', 'force', 'layered', 'subspace', 'subspace3', 'force3'};
            app.drawMode.ValueChangedFcn = createCallbackFcn(app, @drawModeValueChanged, true);
            app.drawMode.Value = 'auto';
            app.saveGraph = uibutton(app.UIFigure, 'push');
            app.saveGraph.ButtonPushedFcn = createCallbackFcn(app, @saveGraphButtonPushed, true);
            app.saveGraph.Text = 'Lưu đồ thị';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Floyd_Warshall

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end